classdef app_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SystemInitializationButton    matlab.ui.control.Button
        Shutter01Button               matlab.ui.control.StateButton
        LightwavelengthDropDownLabel  matlab.ui.control.Label
        LightwavelengthDropDown       matlab.ui.control.DropDown
        LaserPowermWEditFieldLabel    matlab.ui.control.Label
        LaserPowermWEditField         matlab.ui.control.NumericEditField
        lightSheetmodeButtonGroup     matlab.ui.container.ButtonGroup
        FalseButton_2                 matlab.ui.control.RadioButton
        TrueButton                    matlab.ui.control.RadioButton
        FrameRateEditFieldLabel       matlab.ui.control.Label
        FrameRateEditField            matlab.ui.control.NumericEditField
        ExposureTimeDropDownLabel     matlab.ui.control.Label
        ExposureTimeDropDown          matlab.ui.control.DropDown
        DisableCameraButton           matlab.ui.control.Button
        EnableCameraButton            matlab.ui.control.Button
        galvoXSliderLabel             matlab.ui.control.Label
        galvoXSlider                  matlab.ui.control.Slider
        galvoYSlider                  matlab.ui.control.Slider
        galvoYSliderLabel             matlab.ui.control.Label
        galvoXEditFieldLabel          matlab.ui.control.Label
        viewgalvoX                    matlab.ui.control.NumericEditField
        galvoYEditFieldLabel          matlab.ui.control.Label
        viewgalvoY                    matlab.ui.control.NumericEditField
        SaveImageButton               matlab.ui.control.Button
        ResetgalvoButton              matlab.ui.control.Button
        ExtstepsizemmDropDownLabel    matlab.ui.control.Label
        ExtstepsizemmDropDown         matlab.ui.control.DropDown
        LeftButton                    matlab.ui.control.Button
        RightButton                   matlab.ui.control.Button
        ExtPosEditFieldLabel          matlab.ui.control.Label
        ViewExtPos                    matlab.ui.control.NumericEditField
        X_stepListBoxLabel            matlab.ui.control.Label
        X_stepListBox                 matlab.ui.control.ListBox
        Y_stepListBoxLabel            matlab.ui.control.Label
        Y_stepListBox                 matlab.ui.control.ListBox
        Z_stepListBoxLabel            matlab.ui.control.Label
        Z_stepListBox                 matlab.ui.control.ListBox
        X_RightButton                 matlab.ui.control.Button
        X_LeftButton                  matlab.ui.control.Button
        Y_leftButton                  matlab.ui.control.Button
        Y_rightButton                 matlab.ui.control.Button
        Z_leftButton                  matlab.ui.control.Button
        Z_rightButton                 matlab.ui.control.Button
        X_posEditFieldLabel           matlab.ui.control.Label
        View_X_stagepos               matlab.ui.control.NumericEditField
        Y_posEditFieldLabel           matlab.ui.control.Label
        View_Y_stagepos               matlab.ui.control.NumericEditField
        Z_posEditFieldLabel           matlab.ui.control.Label
        View_Z_stagepos               matlab.ui.control.NumericEditField
        T_stepListBoxLabel            matlab.ui.control.Label
        T_stepListBox                 matlab.ui.control.ListBox
        T_posLabel                    matlab.ui.control.Label
        View_T_stagepos               matlab.ui.control.NumericEditField
        T_rightButton                 matlab.ui.control.Button
        T_leftButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        %Property % Description
        portExt = 'COM7'; % Excitation stage
        
        portDet = 'COM15'; % Detection stage
        portFWDet = 'COM11'; % Filter wheel (detection)
        portObis488nm = 'COM4'; % 488nm Laser
        portObis561nm = 'COM5'; % 561nm Laser
        portObis640nm = 'COM6'; % 640nm Laser
        
        pixelSizeUm = 6.5;
        tubeLensFocalDistance = 180; %mm
        objectiveDesignedTubeLensFocal = 180; %mm
        objectiveMag = 10;
        
        expWindowHeight = 30;
        
        controlParameters;
        %laserParameters;
        allLasers;
        %Set the light path to excitation 1
        path1Excitation = 1;
        path2Excitation = 2;
        
        %shutter state
        shutter_state = 4;
        %Set the power
        wavelength;
        intensityInMW;
        focusingFilter;
        %Parameters for the display
        frameRate;
        expTime;
        addLine;   
        lightSheetmode;
        curPath = 1;
        
        % sExtLens control parameters
        expousreTime = [500,250,100,50,25];
        expousreTimeIndex = 3;
        
        f;
        myhandles;
        counter = 1;
        
        
        Stage_step_X;
        Stage_step_Y;
        Stage_step_Z;
        Stage_step_T;
    end
    
    methods (Access = private)


        function [pos] = GetPos(app,serial)
            maxSizeOfString = 7;        
            fprintf(serial,'1TP?');
            pos = fscanf(serial);
            k = strfind(pos,'TP');
            k = k(1);
            pos = pos(k+2:end);
            k = strfind(pos,'e');        
            if (~isempty(k))
                k = k(1);
                scale = pos(k+1:k+3);
                pos = pos(1:k-1);
                switch scale
                    case '-06'
                        pos = num2str(0);
                    case '-05'
                        pos = num2str(0);                
                end                   
            end
        
        if(numel(pos) > maxSizeOfString)
            pos = pos(1:maxSizeOfString);
        end      
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SystemInitializationButton
        function SystemInitializationButtonPushed(app, event)
            addpath('D:\MatlabCodeLSMOrcaMM2\AfgCode','D:\MatlabCodeLSMOrcaMM2\DCMotor',...
    'D:\MatlabCodeLSMOrcaMM2\FilterWheel','D:\MatlabCodeLSMOrcaMM2\OBISGeneral',...
    'D:\MatlabCodeLSMOrcaMM2\UsefullFunctions\CalibrationFunctions');
            avilableLasers = [1 1 1];
            app.lightSheetmode = false;
            % Parameters for MicroManager
            pathToCfgFile = 'C:\Program Files\Micro-Manager-1.4\configuration_05302019.cfg';
            [mmc] = InitiateHardware(pathToCfgFile); 
            cameraLabel = 'HamamatsuHam_DCAM';
            stageLabel = 'XYStage';
            
            % Initiate the filter wheel
            [sFWDet] = InitFW(app.portFWDet);           
            sFWExt = 0;
            % Path 1 excitation
            [sExtLens] = InitTheMotor(app.portExt); 
            fprintf(sExtLens, '1VA1');
            fprintf(sExtLens,['1PA','6']);
                       
            pause(5)
            % Detection objective
            [sDetLens] = InitTheMotor(app.portDet); 
            % Arbitrary function generator
            [afg] = EstablishComAfg();
            
            
            % Establish connection to the MCC DAQ (make sure channels are correct)
            s = daq.createSession('mcc');
            shutters = addAnalogOutputChannel(s,'Board0', 'Ao1', 'Voltage');
            galvoX = addAnalogOutputChannel(s,'Board0', 'Ao2', 'Voltage');
            galvoY= addAnalogOutputChannel(s,'Board0', 'Ao3', 'Voltage');
            
            app.controlParameters = struct('MMC', mmc, 'cameraLabel', cameraLabel, 'stageLabel', stageLabel, ...
    'sFWDet', sFWDet,'sFWExt',sFWExt, 'sExtLens', sExtLens, 'sDetLens',...
    sDetLens,'afg', afg,'shutters',shutters, 'galvoX',galvoX,'galvoY',galvoY,'s',s);

            [dcValueAfterWrite] = SetAfgDCValue(afg, 0);
            fwrite(afg, ':output1 off;');
            
            % Set the Thorlabs galvos to 0 (mcc)
            outputSingleScan(s, [0,0,0]);
            %Set the light path to excitation 1
            %path1Excitation = 1;
            %path2Excitation = 2;
            
            app.curPath = 1;
            downSampleFactor = 1;
            
            %% LASER SETUP (initiate the lasers based on availability)
            %488 nm 
            if (avilableLasers(1))
                [s488nm] = InitObis(app.portObis488nm);    
            end
            %561 nm 
            if (avilableLasers(2))
                [s561nm] = InitObis(app.portObis561nm);    
            end
            %640 nm
            if (avilableLasers(3))
                [s640nm] = InitObis(app.portObis640nm);    
            end
            
            %Define lasers parameters
            %Control type:
            %PS - power supply
            %OBIS - OBIS software
            %FW - filter wheel
            
            %The two rows format is for the second light path
            laserParameters = struct('wavelength', 405, 'maxPower', 50, 'available', false, 'filterEmission', 1,...
                'filterAF', 1,'filterExcitation', 1,'posExtLens', [0; 0], 'posDetLens', [0; 0], 'symmetry',...
                [97; 97], 'Delay', [0 0 0 0 0 0; 0 0 0 0 0 0], 'OptimalDelay', [0; 0], 'FrameRates', [50 25 15 10 7 5], 'serialPort',0,'ControlType','PS',...
                'minVoltage',-600, 'maxVoltage',600, 'chromaticAberrationDetLens', 0.01, 'newFreq', 0);
            numOfActiveLasers = sum(avilableLasers);
            app.allLasers = laserParameters;
            indAllLasers = 0;
            
            %488 laser
            if (avilableLasers(1))
                laser488nm = laserParameters;
                laser488nm.wavelength = 488;
                laser488nm.maxPower = 50; %mW
                laser488nm.available = true;
                laser488nm.filterEmission = 1;
                laser488nm.filterAF = 1;
                laser488nm.filterExcitation = 2;
                laser488nm.posExtLens = [-0.05,4.10992];
                laser488nm.posDetLens = [-.0249,-0.0549];
                laser488nm.symmetry = [80; 90.1]; 
                laser488nm.Delay = [11.86 25.4 39.52 75.1 84.6 118.01; 11.86 25.5 39.52 75.2 77.3 118.01];
                laser488nm.OptimalDelay = [25.4; 25.5];
                laser488nm.FrameRates = [50 25 15 10 7 5];
                laser488nm.serialPort = s488nm;
                laser488nm.ControlType = 'OBIS';
                laser488nm.minVoltage = [-529; -394];
                laser488nm.maxVoltage = [527;350];
                laser488nm.chromaticAberrationDetLens = 0.01;
                indAllLasers = indAllLasers + 1;
                app.allLasers(indAllLasers) = laser488nm;    
            end
            
            
            %561 laser 
            if (avilableLasers(2))
                laser561nm = laserParameters;
                laser561nm.wavelength = 561;
                laser561nm.maxPower = 50;
                laser561nm.available = true;
                laser561nm.filterEmission = 3;
                laser561nm.filterAF = 1;
                laser561nm.filterExcitation = 3;
                laser561nm.posExtLens = [0.04997; -1.62];
                laser561nm.posDetLens = [0.00; 0.00];
                laser561nm.symmetry = [94.9; 97.5]; %[92.4 92.4 91.4 91.3 91.5 91.4]
                laser561nm.Delay = [12.16 25.4 40.62 60.9 87.1 121.8; 12.16 25.6 40.62 60.9 87.1 121.8]; %ms
                laser561nm.OptimalDelay = [25.4; 25.6]; %ms
                laser561nm.FrameRates = [50 25 15 10 7 5];
                laser561nm.serialPort = s561nm;
                laser561nm.ControlType = 'OBIS';
                laser561nm.minVoltage = [-650; -650];
                laser561nm.maxVoltage = [650; 650];
                laser561nm.chromaticAberrationDetLens = 0.01;
                indAllLasers = indAllLasers + 1;
                app.allLasers(indAllLasers) = laser561nm;    
            end
            
            %640 laser 
            if (avilableLasers(3))
                laser640nm = laserParameters;
                laser640nm.wavelength = 640;
                laser640nm.maxPower = 50;
                laser640nm.available = true;
                laser640nm.filterEmission = 4;
                laser640nm.filterAF = 1;
                laser640nm.filterExcitation = 4;
                laser640nm.posExtLens = [-0.07, 4.23992];
                laser640nm.posDetLens = [-0.0049, -0.0249];
                laser640nm.symmetry = [80.1; 90.1]; %[92.4 92.4 91.4 91.3 91.5 91.4]
                laser640nm.Delay = [12.16 25.5 75 75.3 87.1 121.8; 12.16 25.4 75 75.2 87.1 121.8]; %ms
                laser640nm.OptimalDelay = [25.5; 25.4]; %ms
                laser640nm.FrameRates = [50 25 15 10 7 5];
                laser640nm.serialPort = s640nm;
                laser640nm.ControlType = 'OBIS';
                laser640nm.minVoltage = [-549;-414];
                laser640nm.maxVoltage = [513;319];
                laser640nm.chromaticAberrationDetLens = 0.01;
                indAllLasers = indAllLasers + 1;
                app.allLasers(indAllLasers) = laser640nm;    
            end
            %Run the GUi for camera and motors find the different location of the light-sheet
            %The galvo poisition should be zero
            fwrite(afg, ':output1 off;'); 
            fwrite(afg, ':output2 off;'); 
            
            %Set the power
            app.wavelength = 561;
            app.intensityInMW = 3;
            app.focusingFilter = true;

            %Parameters for the display
            app.frameRate = 6;
            app.expTime = 100; %ms
            app.addLine = false;
            pause(1);
            SwitchOnOffObis488nm(app.allLasers(1).serialPort,0);
            SwitchOnOffObis488nm(app.allLasers(2).serialPort,1);
            SwitchOnOffObis488nm(app.allLasers(3).serialPort,0);
            fprintf(app.controlParameters.sFWDet,'pos=1');
            
            app.Stage_step_X = 10;
            app.Stage_step_Y = 10;
            app.Stage_step_Z = 10;
            app.Stage_step_T = 0;
            
            app.View_X_stagepos.Value = app.controlParameters.MMC.getXPosition();
            app.View_Y_stagepos.Value = app.controlParameters.MMC.getYPosition();
            app.View_Z_stagepos.Value = app.controlParameters.MMC.getPosition('ZStage:Z:32');
            app.View_T_stagepos.Value = app.controlParameters.MMC.getPosition('ZStage:T:32');
            app.ViewExtPos.Value = str2num(GetPos(app,sExtLens));
        end

        % Value changed function: LightwavelengthDropDown
        function LightwavelengthDropDownValueChanged(app, event)
            value = app.LightwavelengthDropDown.Value;
            %powerInMW = 2; % Keep very low (<3)
            %ChangePowerObis488nm(app.allLasers(2).serialPort, powerInMW);
            if value == '488nm'
                SwitchOnOffObis488nm(app.allLasers(1).serialPort,1);
                SwitchOnOffObis488nm(app.allLasers(2).serialPort,0);
                SwitchOnOffObis488nm(app.allLasers(3).serialPort,0);
                fprintf(app.controlParameters.sFWDet,'pos=2');
            end
            if value == '561nm'
                SwitchOnOffObis488nm(app.allLasers(1).serialPort,0);
                SwitchOnOffObis488nm(app.allLasers(2).serialPort,1);
                SwitchOnOffObis488nm(app.allLasers(3).serialPort,0);
                fprintf(app.controlParameters.sFWDet,'pos=1');
            end
            if value == '640nm'
                SwitchOnOffObis488nm(app.allLasers(1).serialPort,0);
                SwitchOnOffObis488nm(app.allLasers(2).serialPort,0);
                SwitchOnOffObis488nm(app.allLasers(3).serialPort,1);
                fprintf(app.controlParameters.sFWDet,'pos=3');
            end
            if value == 'None'
                SwitchOnOffObis488nm(app.allLasers(1).serialPort,0);
                SwitchOnOffObis488nm(app.allLasers(2).serialPort,0);
                SwitchOnOffObis488nm(app.allLasers(3).serialPort,0);
            end
        end

        % Value changed function: Shutter01Button
        function Shutter01ButtonValueChanged(app, event)
            value = app.Shutter01Button.Value;
            if value == 1
                app.shutter_state = 4;
                outputSingleScan(app.controlParameters.s,[app.shutter_state,0,0])
            else
                app.shutter_state = 0;
                outputSingleScan(app.controlParameters.s,[app.shutter_state,0,0])
            end
                              
        end

        % Value changed function: LaserPowermWEditField
        function LaserPowermWEditFieldValueChanged(app, event)
            value = app.LaserPowermWEditField.Value;
            if value > 5
                value = 5;
            elseif value < 0
                value = 5;
            end
            ChangePowerObis488nm(app.allLasers(1).serialPort, value);
            ChangePowerObis488nm(app.allLasers(2).serialPort, value);
            ChangePowerObis488nm(app.allLasers(3).serialPort, value);
        end

        % Selection changed function: lightSheetmodeButtonGroup
        function lightSheetmodeButtonGroupSelectionChanged(app, event)
            selectedButton = app.lightSheetmodeButtonGroup.SelectedObject;
            if selectedButton.Value == true
                app.lightSheetmode = true;                
            elseif selectedButton.Value == false
                app.lightSheetmode = false;
            end
        end

        % Value changed function: FrameRateEditField
        function FrameRateEditFieldValueChanged(app, event)
            value = app.FrameRateEditField.Value;
            if value > 7
                value = 7;
            elseif value < 2
                value = 2;
            end
            app.frameRate = value;
        end

        % Value changed function: ExposureTimeDropDown
        function ExposureTimeDropDownValueChanged(app, event)
            value = app.ExposureTimeDropDown.Value;
            numOfImages = 1000000;
            intervalMs = 0;
            stopOnOverflow = false;
            if app.lightSheetmode == true
                app.controlParameters.MMC.stopSequenceAcquisition;
                app.controlParameters.MMC.clearCircularBuffer;
                if string(value) == '25ms'
                    app.controlParameters.MMC.setProperty(app.controlParameters.cameraLabel,'Exposure',app.expousreTime(1));
                elseif string(value) == '50ms'
                    app.controlParameters.MMC.setProperty(app.controlParameters.cameraLabel,'Exposure',app.expousreTime(2));
                elseif string(value) == '100ms'
                    app.controlParameters.MMC.setProperty(app.controlParameters.cameraLabel,'Exposure',app.expousreTime(3));
                elseif string(value) == '250ms'
                    app.controlParameters.MMC.setProperty(app.controlParameters.cameraLabel,'Exposure',app.expousreTime(4));
                elseif string(value) == '500ms'
                    app.controlParameters.MMC.setProperty(app.controlParameters.cameraLabel,'Exposure',app.expousreTime(5));
                end
                numOfImages = 1000000;
                intervalMs = 0;
                stopOnOverflow = false;
                app.controlParameters.MMC.stopSequenceAcquisition;
                app.controlParameters.MMC.clearCircularBuffer;
                app.controlParameters.MMC.startSequenceAcquisition(numOfImages, intervalMs, stopOnOverflow);
                pause(0.2)
            end
        end

        % Button pushed function: DisableCameraButton
        function DisableCameraButtonPushed(app, event)
            app.myhandles.continue = false; 
            %app.controlParameters.MMC.stopSequenceAcquisition;
            close(app.f);
        end

        % Button pushed function: EnableCameraButton
        function EnableCameraButtonPushed(app, event)
            app.f = figure;
            app.f.Position = [10 500 1024 1024];
            set(app.f,'toolbar','figure');
            hAxes = subplot(1,1,1);
            app.controlParameters.MMC.stopSequenceAcquisition;
            %Make sure the buffer is empty
            app.controlParameters.MMC.clearCircularBuffer;
            %Debug
            app.controlParameters.MMC.setCircularBufferMemoryFootprint(1024*10);
            numOfImages = 1000000;
            intervalMs = 0;
            stopOnOverflow = false;
            app.controlParameters.MMC.startSequenceAcquisition(numOfImages, intervalMs, stopOnOverflow);
            checkBuffer = app.controlParameters.MMC.getRemainingImageCount();
            if checkBuffer==0
                pause(2);
            end
            
            % set the speed of excitation and detection stage
            fprintf(app.controlParameters.sExtLens, '1VA1');   
            fprintf(app.controlParameters.sDetLens,'1VA0.25');
            
            
            image = app.controlParameters.MMC.getLastImage();  % returned as a 1D array of signed integers in row-major order
            width = app.controlParameters.MMC.getImageWidth();
            height = app.controlParameters.MMC.getImageHeight();
            pixelType = 'uint16';
            image = typecast(image, pixelType);      % pixels must be interpreted as unsigned integers
            image = reshape(image, [width, height]); % image should be interpreted as a 2D array
            image = rot90(image);
            hImage = imshow(image,'Parent',hAxes, 'DisplayRange', [0,50000]);
            colormapeditor;
                
            app.myhandles = guihandles(app.f);
            app.myhandles.saveImage = false;
            %app.myhandles.shutters = app.controlParameters.shutters;
            app.myhandles.continue = true;
            app.myhandles.expousreTime = [500 250 100 50 25];
            app.myhandles.expousreTimeIndex = 3; 
            
            app.myhandles.galvoX = app.controlParameters.galvoX;
            app.myhandles.galvoY = app.controlParameters.galvoY;
            app.myhandles.galvoXVoltage = 0;
            app.myhandles.galvoYVoltage = 0;
            app.myhandles.s = app.controlParameters.s;
            app.myhandles.saveImage = false;
            
            % stepsize of excitation
            app.myhandles.sExt = app.controlParameters.sExtLens;
            app.myhandles.stepSizeExt = 0.1;
            
            
            while (app.myhandles.continue)
                guidata(app.f,app.myhandles);
                checkBuffer = app.controlParameters.MMC.getRemainingImageCount();
                if checkBuffer==0
                    pause(.6);
                end
                startTime = tic;
                image = app.controlParameters.MMC.getLastImage();
                image = typecast(image, pixelType);      % pixels must be interpreted as unsigned integers
                image = reshape(image, [width, height]); % image should be interpreted as a 2D array
                image = rot90(image);
                set(hImage,'CData',image);
                
                if (app.myhandles.saveImage)
                    app.myhandles.saveImage = false;
                    nameOfFile = [date,'_','Chenli',num2str(321),...
                    num2str(123),'.tiff'];
                    app.counter = app.counter + 1;
                    imwrite(image, nameOfFile);    
                    guidata(app.f,app.myhandles);
                end
                
                elTime = toc(startTime);
                if (elTime <= (app.myhandles.expousreTime(app.myhandles.expousreTimeIndex)/1000))
                    pause(app.myhandles.expousreTime(app.myhandles.expousreTimeIndex)/1000 - elTime);                
                else
                    pause(0.15);
                end
                %app.myhandles = guidata(app.f);
            end
        end

        % Value changed function: galvoXSlider
        function galvoXSliderValueChanged(app, event)
            value = app.galvoXSlider.Value;
            app.myhandles.galvoXVoltage = value;
            app.viewgalvoX.Value = value;
            outputSingleScan(app.myhandles.s, [app.shutter_state,app.myhandles.galvoXVoltage,app.myhandles.galvoYVoltage]);
        end

        % Value changed function: galvoYSlider
        function galvoYSliderValueChanged(app, event)
            value = app.galvoYSlider.Value;
            app.myhandles.galvoYVoltage = value;
            app.viewgalvoY.Value = value;
            outputSingleScan(app.myhandles.s, [app.shutter_state,app.myhandles.galvoXVoltage,app.myhandles.galvoYVoltage]);
        end

        % Button pushed function: ResetgalvoButton
        function ResetgalvoButtonPushed(app, event)
            app.myhandles.galvoXVoltage = 0;
            app.myhandles.galvoYVoltage = 0;
            app.viewgalvoX.Value = 0;
            app.viewgalvoY.Value = 0;
            outputSingleScan(app.myhandles.s, [app.shutter_state,app.myhandles.galvoXVoltage,app.myhandles.galvoYVoltage]);
        end

        % Value changed function: viewgalvoX
        function viewgalvoXValueChanged(app, event)
            value = app.viewgalvoX.Value;
            app.myhandles.galvoXVoltage = value;
            outputSingleScan(app.myhandles.s, [app.shutter_state,app.myhandles.galvoXVoltage,app.myhandles.galvoYVoltage]);
        end

        % Value changed function: viewgalvoY
        function viewgalvoYValueChanged(app, event)
            value = app.viewgalvoY.Value;
            app.myhandles.galvoYVoltage = value;
            outputSingleScan(app.myhandles.s, [app.shutter_state,app.myhandles.galvoXVoltage,app.myhandles.galvoYVoltage]);
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
            app.myhandles.saveImage = true;
        end

        % Value changed function: ExtstepsizemmDropDown
        function ExtstepsizemmDropDownValueChanged(app, event)
            value = app.ExtstepsizemmDropDown.Value;
            
            if str2num(value) == 0.2
                app.myhandles.stepSizeExt = 0.2;
            end
            if str2num(value) == 0.1
                app.myhandles.stepSizeExt = 0.1;
            end
            if str2num(value) == 0.01
                app.myhandles.stepSizeExt = 0.01;
            end
            if str2num(value) == 0.001
                app.myhandles.stepSizeExt = 0.001;
            end
            if str2num(value) == 0.5
                app.myhandles.stepSizeExt = 0.5;
            end
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            fprintf(app.myhandles.sExt,['1PR',num2str(app.myhandles.stepSizeExt)]);
            pause(0.5)
            maxSizeOfString = 7;  
            fprintf(app.myhandles.sExt,'1TP?');
            pos = fscanf(app.myhandles.sExt);
            k = strfind(pos,'TP');
            k = k(1);
            pos = pos(k+2:end);
            k = strfind(pos,'e');        
            if (~isempty(k))
                k = k(1);
                scale = pos(k+1:k+3);
                pos = pos(1:k-1);
                switch scale
                    case '-06'
                    pos = num2str(0);
                    case '-05'
                    pos = num2str(0);                
                end                   
            end
        
            if(numel(pos) > maxSizeOfString)
                pos = pos(1:maxSizeOfString);
            end
            app.ViewExtPos.Value = str2num(pos);
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            fprintf(app.myhandles.sExt,['1PR-',num2str(app.myhandles.stepSizeExt)]);
            pause(0.5)
            maxSizeOfString = 7;  
            fprintf(app.myhandles.sExt,'1TP?');
            pos = fscanf(app.myhandles.sExt);
            k = strfind(pos,'TP');
            k = k(1);
            pos = pos(k+2:end);
            k = strfind(pos,'e');        
            if (~isempty(k))
                k = k(1);
                scale = pos(k+1:k+3);
                pos = pos(1:k-1);
                switch scale
                    case '-06'
                    pos = num2str(0);
                    case '-05'
                    pos = num2str(0);                
                end                   
            end
        
            if(numel(pos) > maxSizeOfString)
                pos = pos(1:maxSizeOfString);
            end
            app.ViewExtPos.Value = str2num(pos);
        end

        % Value changed function: X_stepListBox
        function X_stepListBoxValueChanged(app, event)
            value = app.X_stepListBox.Value;
            if convertCharsToStrings(value) == '10um'
                app.Stage_step_X = 10;
            end
            if convertCharsToStrings(value) == '50um'
                app.Stage_step_X = 50;
            end
            if convertCharsToStrings(value) == '100um'
                app.Stage_step_X = 100;
            end
            if convertCharsToStrings(value) == '500um'
                app.Stage_step_X = 500;
            end
            if convertCharsToStrings(value) == '1mm'
                app.Stage_step_X = 1000;
            end
            if convertCharsToStrings(value) == '2mm'
                app.Stage_step_X = 2000;
            end
            if convertCharsToStrings(value) == '5mm'
                app.Stage_step_X = 5000;
            end
        end

        % Value changed function: Y_stepListBox
        function Y_stepListBoxValueChanged(app, event)
            value = app.Y_stepListBox.Value;
            if convertCharsToStrings(value) == '10um'
                app.Stage_step_Y = 10;
            end
            if convertCharsToStrings(value) == '50um'
                app.Stage_step_Y = 50;
            end
            if convertCharsToStrings(value) == '100um'
                app.Stage_step_Y = 100;
            end
            if convertCharsToStrings(value) == '500um'
                app.Stage_step_Y = 500;
            end
            if convertCharsToStrings(value) == '1mm'
                app.Stage_step_Y = 1000;
            end
            if convertCharsToStrings(value) == '2mm'
                app.Stage_step_Y = 2000;
            end
            if convertCharsToStrings(value) == '5mm'
                app.Stage_step_Y = 5000;
            end
        end

        % Value changed function: Z_stepListBox
        function Z_stepListBoxValueChanged(app, event)
            value = app.Z_stepListBox.Value;
            if convertCharsToStrings(value) == '10um'
                app.Stage_step_Z = 10;
            end
            if convertCharsToStrings(value) == '50um'
                app.Stage_step_Z = 50;
            end
            if convertCharsToStrings(value) == '100um'
                app.Stage_step_Z = 100;
            end
            if convertCharsToStrings(value) == '500um'
                app.Stage_step_Z = 500;
            end
            if convertCharsToStrings(value) == '1mm'
                app.Stage_step_Z = 1000;
            end
            if convertCharsToStrings(value) == '2mm'
                app.Stage_step_Z = 2000;
            end
            if convertCharsToStrings(value) == '5mm'
                app.Stage_step_Z = 5000;
            end
        end

        % Button pushed function: X_LeftButton
        function X_LeftButtonPushed(app, event)
            app.controlParameters.MMC.setRelativeXYPosition(-app.Stage_step_X,0);
            app.View_X_stagepos.Value = app.controlParameters.MMC.getXPosition();
        end

        % Button pushed function: Y_rightButton
        function Y_rightButtonPushed(app, event)
            app.controlParameters.MMC.setRelativeXYPosition(0,app.Stage_step_Y);
            app.View_Y_stagepos.Value = app.controlParameters.MMC.getYPosition();
        end

        % Button pushed function: X_RightButton
        function X_RightButtonPushed(app, event)
            app.controlParameters.MMC.setRelativeXYPosition(app.Stage_step_X,0);
            app.View_X_stagepos.Value = app.controlParameters.MMC.getXPosition();
        end

        % Button pushed function: Y_leftButton
        function Y_leftButtonPushed(app, event)
            app.controlParameters.MMC.setRelativeXYPosition(0,-app.Stage_step_Y);
            app.View_Y_stagepos.Value = app.controlParameters.MMC.getYPosition();
        end

        % Button pushed function: Z_leftButton
        function Z_leftButtonPushed(app, event)
            app.controlParameters.MMC.setRelativePosition('ZStage:Z:32',-app.Stage_step_Z);
            app.View_Z_stagepos.Value = app.controlParameters.MMC.getPosition('ZStage:Z:32');
        end

        % Button pushed function: Z_rightButton
        function Z_rightButtonPushed(app, event)
            app.controlParameters.MMC.setRelativePosition('ZStage:Z:32',app.Stage_step_Z);
            app.View_Z_stagepos.Value = app.controlParameters.MMC.getPosition('ZStage:Z:32');
        end

        % Value changed function: T_stepListBox
        function T_stepListBoxValueChanged(app, event)
            value = app.T_stepListBox.Value;
            if convertCharsToStrings(value) == '10'
                app.Stage_step_T = 10;
            end
            if convertCharsToStrings(value) == '50'
                app.Stage_step_T = 50;
            end
            if convertCharsToStrings(value) == '100'
                app.Stage_step_T = 100;
            end
            if convertCharsToStrings(value) == '500'
                app.Stage_step_T = 500;
            end
            if convertCharsToStrings(value) == '1000'
                app.Stage_step_T = 1000;
            end
            if convertCharsToStrings(value) == '2000'
                app.Stage_step_T = 2000;
            end
            if convertCharsToStrings(value) == '5000'
                app.Stage_step_T = 5000;
            end
        end

        % Button pushed function: T_rightButton
        function T_rightButtonPushed(app, event)
            app.controlParameters.MMC.setRelativePosition('ZStage:T:32',-app.Stage_step_T);
            app.View_T_stagepos.Value = app.controlParameters.MMC.getPosition('ZStage:T:32');
        end

        % Button pushed function: T_leftButton
        function T_leftButtonPushed(app, event)
            app.controlParameters.MMC.setRelativePosition('ZStage:T:32',app.Stage_step_T);
            app.View_T_stagepos.Value = app.controlParameters.MMC.getPosition('ZStage:T:32');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SystemInitializationButton = uibutton(app.UIFigure, 'push');
            app.SystemInitializationButton.ButtonPushedFcn = createCallbackFcn(app, @SystemInitializationButtonPushed, true);
            app.SystemInitializationButton.Text = 'System Initialization';
            app.Shutter01Button = uibutton(app.UIFigure, 'state');
            app.Shutter01Button.ValueChangedFcn = createCallbackFcn(app, @Shutter01ButtonValueChanged, true);
            app.Shutter01Button.Text = 'Shutter01';
            app.LightwavelengthDropDownLabel = uilabel(app.UIFigure);
            app.LightwavelengthDropDownLabel.Text = 'Light wavelength';
            app.LightwavelengthDropDown = uidropdown(app.UIFigure);
            app.LightwavelengthDropDown.Items = {'None', '488nm', '561nm', '640nm'};
            app.LightwavelengthDropDown.ValueChangedFcn = createCallbackFcn(app, @LightwavelengthDropDownValueChanged, true);
            app.LightwavelengthDropDown.Value = '561nm';
            app.LaserPowermWEditFieldLabel = uilabel(app.UIFigure);
            app.LaserPowermWEditFieldLabel.Text = 'LaserPower(mW)';
            app.LaserPowermWEditField = uieditfield(app.UIFigure, 'numeric');
            app.LaserPowermWEditField.ValueChangedFcn = createCallbackFcn(app, @LaserPowermWEditFieldValueChanged, true);
            app.LaserPowermWEditField.Value = 3;
            app.lightSheetmodeButtonGroup = uibuttongroup(app.UIFigure);
            app.lightSheetmodeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @lightSheetmodeButtonGroupSelectionChanged, true);
            app.lightSheetmodeButtonGroup.Title = 'lightSheetmode';
            app.FalseButton_2 = uiradiobutton(app.lightSheetmodeButtonGroup);
            app.FalseButton_2.Text = 'False';
            app.FalseButton_2.Value = true;
            app.TrueButton = uiradiobutton(app.lightSheetmodeButtonGroup);
            app.TrueButton.Text = 'True';
            app.FrameRateEditFieldLabel = uilabel(app.UIFigure);
            app.FrameRateEditFieldLabel.Text = 'FrameRate';
            app.FrameRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrameRateEditField.ValueChangedFcn = createCallbackFcn(app, @FrameRateEditFieldValueChanged, true);
            app.FrameRateEditField.Value = 6;
            app.ExposureTimeDropDownLabel = uilabel(app.UIFigure);
            app.ExposureTimeDropDownLabel.Text = 'Exposure Time';
            app.ExposureTimeDropDown = uidropdown(app.UIFigure);
            app.ExposureTimeDropDown.Items = {'25ms', '50ms', '100ms', '250ms', '500ms'};
            app.ExposureTimeDropDown.ValueChangedFcn = createCallbackFcn(app, @ExposureTimeDropDownValueChanged, true);
            app.ExposureTimeDropDown.Value = '100ms';
            app.DisableCameraButton = uibutton(app.UIFigure, 'push');
            app.DisableCameraButton.ButtonPushedFcn = createCallbackFcn(app, @DisableCameraButtonPushed, true);
            app.DisableCameraButton.Text = 'Disable Camera';
            app.EnableCameraButton = uibutton(app.UIFigure, 'push');
            app.EnableCameraButton.ButtonPushedFcn = createCallbackFcn(app, @EnableCameraButtonPushed, true);
            app.EnableCameraButton.Text = 'Enable Camera';
            app.galvoXSliderLabel = uilabel(app.UIFigure);
            app.galvoXSliderLabel.Text = 'galvoX';
            app.galvoXSlider = uislider(app.UIFigure);
            app.galvoXSlider.Limits = [-0.5 0.5];
            app.galvoXSlider.MajorTicks = [-0.5 -0.4 -0.3 -0.2 -0.1 0 0.1 0.2 0.3 0.4 0.5];
            app.galvoXSlider.ValueChangedFcn = createCallbackFcn(app, @galvoXSliderValueChanged, true);
            app.galvoYSlider = uislider(app.UIFigure);
            app.galvoYSlider.Limits = [-0.5 0.5];
            app.galvoYSlider.MajorTicks = [-0.5 -0.4 -0.3 -0.2 -0.1 0 0.1 0.2 0.3 0.4 0.5];
            app.galvoYSlider.ValueChangedFcn = createCallbackFcn(app, @galvoYSliderValueChanged, true);
            app.galvoYSliderLabel = uilabel(app.UIFigure);
            app.galvoYSliderLabel.Text = 'galvoY';
            app.galvoXEditFieldLabel = uilabel(app.UIFigure);
            app.galvoXEditFieldLabel.Text = 'galvoX';
            app.viewgalvoX = uieditfield(app.UIFigure, 'numeric');
            app.viewgalvoX.ValueChangedFcn = createCallbackFcn(app, @viewgalvoXValueChanged, true);
            app.galvoYEditFieldLabel = uilabel(app.UIFigure);
            app.galvoYEditFieldLabel.Text = 'galvoY';
            app.viewgalvoY = uieditfield(app.UIFigure, 'numeric');
            app.viewgalvoY.ValueChangedFcn = createCallbackFcn(app, @viewgalvoYValueChanged, true);
            app.SaveImageButton = uibutton(app.UIFigure, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Text = 'Save Image';
            app.ResetgalvoButton = uibutton(app.UIFigure, 'push');
            app.ResetgalvoButton.ButtonPushedFcn = createCallbackFcn(app, @ResetgalvoButtonPushed, true);
            app.ResetgalvoButton.Text = 'Reset galvo';
            app.ExtstepsizemmDropDownLabel = uilabel(app.UIFigure);
            app.ExtstepsizemmDropDownLabel.Text = 'Ext stepsize(mm)';
            app.ExtstepsizemmDropDown = uidropdown(app.UIFigure);
            app.ExtstepsizemmDropDown.Items = {'0.5', '0.2', '0.1', '0.01', '0.001'};
            app.ExtstepsizemmDropDown.ValueChangedFcn = createCallbackFcn(app, @ExtstepsizemmDropDownValueChanged, true);
            app.ExtstepsizemmDropDown.Value = '0.1';
            app.LeftButton = uibutton(app.UIFigure, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.RightButton = uibutton(app.UIFigure, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.ExtPosEditFieldLabel = uilabel(app.UIFigure);
            app.ExtPosEditFieldLabel.Text = 'Ext Pos';
            app.ViewExtPos = uieditfield(app.UIFigure, 'numeric');
            app.ViewExtPos.Value = 6;
            app.X_stepListBoxLabel = uilabel(app.UIFigure);
            app.X_stepListBoxLabel.Text = 'X_step';
            app.X_stepListBox = uilistbox(app.UIFigure);
            app.X_stepListBox.Items = {'10um', '50um', '100um', '500um', '1mm', '2mm', '5mm'};
            app.X_stepListBox.ValueChangedFcn = createCallbackFcn(app, @X_stepListBoxValueChanged, true);
            app.X_stepListBox.Value = '10um';
            app.Y_stepListBoxLabel = uilabel(app.UIFigure);
            app.Y_stepListBoxLabel.Text = 'Y_step';
            app.Y_stepListBox = uilistbox(app.UIFigure);
            app.Y_stepListBox.Items = {'10um', '50um', '100um', '500um', '1mm', '2mm', '5mm'};
            app.Y_stepListBox.ValueChangedFcn = createCallbackFcn(app, @Y_stepListBoxValueChanged, true);
            app.Y_stepListBox.Value = '10um';
            app.Z_stepListBoxLabel = uilabel(app.UIFigure);
            app.Z_stepListBoxLabel.Text = 'Z_step';
            app.Z_stepListBox = uilistbox(app.UIFigure);
            app.Z_stepListBox.Items = {'10um', '50um', '100um', '500um', '1mm', '2mm', '5mm'};
            app.Z_stepListBox.ValueChangedFcn = createCallbackFcn(app, @Z_stepListBoxValueChanged, true);
            app.Z_stepListBox.Value = '10um';
            app.X_RightButton = uibutton(app.UIFigure, 'push');
            app.X_RightButton.ButtonPushedFcn = createCallbackFcn(app, @X_RightButtonPushed, true);
            app.X_RightButton.Text = 'X_Right';
            app.X_LeftButton = uibutton(app.UIFigure, 'push');
            app.X_LeftButton.ButtonPushedFcn = createCallbackFcn(app, @X_LeftButtonPushed, true);
            app.X_LeftButton.Text = 'X_Left';
            app.Y_leftButton = uibutton(app.UIFigure, 'push');
            app.Y_leftButton.ButtonPushedFcn = createCallbackFcn(app, @Y_leftButtonPushed, true);
            app.Y_leftButton.Text = 'Y_left';
            app.Y_rightButton = uibutton(app.UIFigure, 'push');
            app.Y_rightButton.ButtonPushedFcn = createCallbackFcn(app, @Y_rightButtonPushed, true);
            app.Y_rightButton.Text = 'Y_right';
            app.Z_leftButton = uibutton(app.UIFigure, 'push');
            app.Z_leftButton.ButtonPushedFcn = createCallbackFcn(app, @Z_leftButtonPushed, true);
            app.Z_leftButton.Text = 'Z_left';
            app.Z_rightButton = uibutton(app.UIFigure, 'push');
            app.Z_rightButton.ButtonPushedFcn = createCallbackFcn(app, @Z_rightButtonPushed, true);
            app.Z_rightButton.Text = 'Z_right';
            app.X_posEditFieldLabel = uilabel(app.UIFigure);
            app.X_posEditFieldLabel.Text = 'X_pos';
            app.View_X_stagepos = uieditfield(app.UIFigure, 'numeric');
            app.Y_posEditFieldLabel = uilabel(app.UIFigure);
            app.Y_posEditFieldLabel.Text = 'Y_pos';
            app.View_Y_stagepos = uieditfield(app.UIFigure, 'numeric');
            app.Z_posEditFieldLabel = uilabel(app.UIFigure);
            app.Z_posEditFieldLabel.Text = 'Z_pos';
            app.View_Z_stagepos = uieditfield(app.UIFigure, 'numeric');
            app.T_stepListBoxLabel = uilabel(app.UIFigure);
            app.T_stepListBoxLabel.Text = 'T_step';
            app.T_stepListBox = uilistbox(app.UIFigure);
            app.T_stepListBox.Items = {'10', '50', '100', '500', '1000', '2000', '5000'};
            app.T_stepListBox.ValueChangedFcn = createCallbackFcn(app, @T_stepListBoxValueChanged, true);
            app.T_stepListBox.Value = '10';
            app.T_posLabel = uilabel(app.UIFigure);
            app.T_posLabel.Text = 'T_pos';
            app.View_T_stagepos = uieditfield(app.UIFigure, 'numeric');
            app.T_rightButton = uibutton(app.UIFigure, 'push');
            app.T_rightButton.ButtonPushedFcn = createCallbackFcn(app, @T_rightButtonPushed, true);
            app.T_rightButton.Text = 'T_right';
            app.T_leftButton = uibutton(app.UIFigure, 'push');
            app.T_leftButton.ButtonPushedFcn = createCallbackFcn(app, @T_leftButtonPushed, true);
            app.T_leftButton.Text = 'T_left';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end